classdef RHEOL_GUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure                       matlab.ui.Figure
        uipanelActions               matlab.ui.container.Panel
        pushbuttonExportModel        matlab.ui.control.Button
        pushbuttonExportRheology     matlab.ui.control.Button
        pushbuttonExportTable        matlab.ui.control.Button
        pushbuttonExportProfile      matlab.ui.control.Button
        editGraphic                  matlab.ui.control.EditField
        pushbuttonReduceBrittle      matlab.ui.control.Button
        pushbuttonReduceGrainSize    matlab.ui.control.Button
        pushbuttonEffectiveRheology  matlab.ui.control.Button
        uipanelGraphics              matlab.ui.container.Panel
        pushbuttonDrawStrength       matlab.ui.control.Button
        pushbuttonDrawTemperature    matlab.ui.control.Button
        pushbuttonDrawStructure      matlab.ui.control.Button
        axesGrainsize                matlab.ui.control.UIAxes
        axesStrength                 matlab.ui.control.UIAxes
        axesTemperature              matlab.ui.control.UIAxes
        axesStructure                matlab.ui.control.UIAxes
        uipanelLayers                matlab.ui.container.Panel
        uibuttongroup1               matlab.ui.container.ButtonGroup
        uibuttongroup3               matlab.ui.container.ButtonGroup
        grainSizeLabel               matlab.ui.control.Label
        editFriction                 matlab.ui.control.EditField
        listboxBrittle               matlab.ui.control.ListBox
        editStrengthLimit            matlab.ui.control.EditField
        checkboxStrength             matlab.ui.control.CheckBox
        uibuttongroup4               matlab.ui.container.ButtonGroup
        editGrainSize                matlab.ui.control.EditField
        checkboxNoDuctile            matlab.ui.control.CheckBox
        popupmenuGrainSize           matlab.ui.control.DropDown
        listboxRheology              matlab.ui.control.ListBox
        pushbuttonDeleteRock         matlab.ui.control.Button
        pushbuttonAddRock            matlab.ui.control.Button
        listboxRocks                 matlab.ui.control.ListBox
        popupmenuRockType            matlab.ui.control.DropDown
        checkboxHydrostatic          matlab.ui.control.CheckBox
        fluidPressureLabel           matlab.ui.control.Label
        editPf                       matlab.ui.control.EditField
        thicknessLabel               matlab.ui.control.Label
        editThickness                matlab.ui.control.EditField
        pushbuttonDeleteLayer        matlab.ui.control.Button
        pushbuttonAddLayer           matlab.ui.control.Button
        listboxLayers                matlab.ui.control.ListBox
        pushbuttonChange             matlab.ui.control.Button
        uipanelFile                  matlab.ui.container.Panel
        pushbuttonUpdateList         matlab.ui.control.Button
        pushbuttonRead               matlab.ui.control.Button
        inputFileNameLabel           matlab.ui.control.Label
        popupmenuInput               matlab.ui.control.DropDown
        pushbuttonWrite              matlab.ui.control.Button
        outputFileLabel              matlab.ui.control.Label
        editOutput                   matlab.ui.control.EditField
        uipanelGlobal                matlab.ui.container.Panel
        popupmenuTemperatureFile     matlab.ui.control.DropDown
        popupmenuDID                 matlab.ui.control.DropDown
        gravityLabel                 matlab.ui.control.Label
        editGravity                  matlab.ui.control.EditField
        surfacePressureLabel         matlab.ui.control.Label
        editP0                       matlab.ui.control.EditField
        adiabaticTLabel              matlab.ui.control.Label
        editTm                       matlab.ui.control.EditField
        popupmenuTemperature         matlab.ui.control.DropDown
        pushbuttonPlanetDefault      matlab.ui.control.Button
        popupmenuPlanet              matlab.ui.control.DropDown
        TGradientLabel               matlab.ui.control.Label
        surfaceTLabel                matlab.ui.control.Label
        editGs                       matlab.ui.control.EditField
        editTs                       matlab.ui.control.EditField
        strainRateLabel              matlab.ui.control.Label
        editStrainRate               matlab.ui.control.EditField
    end


    properties (Access = public)
        model % model structure
    end

    properties (Access = public)
        flNames % Description
    end

    properties (Access = public)
        globalModel % Description
    end

    properties (Access = public)
        planet % model structure
    end

    properties (Access = public)
        rock % Description
    end

    properties (Access = public)
        nplanets % model structure
    end

    properties (Access = public)
        nrock % Description
    end

    properties (Access = public)
        modelGlobal
    end

    properties (Access = public)
        mglobal
    end

    properties (Access = public)
        globalDiscrepant
    end

    %%
    methods (Access = private)
% % %         function edit11_CreateFcn(app)
% % %             if ispc && isequal(get(app,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
% % %                 app.BackgroundColor = 'white';
% % %             end
% % %         end

% % %         function edit12_CreateFcn(app)
% % %             if ispc && isequal(get(app,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
% % %                 app.BackgroundColor = 'white';
% % %             end
% % %         end
% % % 
% % %         function edit8_Callback(app)
% % %             setGlobalDiscrepant(app);
% % %             %GlobalDiscrepant(app);
% % %         end

% % %         function edit8_CreateFcn(app)
% % %             if ispc && isequal(get(app,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
% % %                 app.BackgroundColor = 'white';
% % %             end
% % %         end

% % %         function editTemperatureFile_Callback(app)
% % %             setGlobalDiscrepant(app);
% % %         end

% % %         function editTemperatureFile_CreateFcn(app)
% % %             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
% % %                 app.BackgroundColor = 'white';
% % %             end
% % %         end

% % %         function listbox1_Callback(app)
% % %             setGlobalDiscrepant(app);
% % %         end

% % %         function listbox1_CreateFcn(app)
% % %             if ispc && isequal(get(app,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
% % %                 app.BackgroundColor = 'white';
% % %             end
% % %         end

        function populateGlobal(app)
            mglobal = app.modelGlobal;
            %  load planet.mat;
            %app.popupmenuPlanet.Items = {app.planet.name};
            app.popupmenuPlanet.Value = app.popupmenuPlanet.Items(mglobal.ip);
            %app.popupmenuPlanet.Value = planet(mglobal.ip).name;
            %app.popupmenuTemperature.Items = {'linear','error fn'};
            app.popupmenuTemperature.Value = app.popupmenuTemperature.Items(mglobal.thid);% thermal id
            %app.popupmenuDID.Items = {'Compression','Extension','Strike-slip'};
            app.popupmenuDID.Value = app.popupmenuDID.Items(mglobal.did);% deformation id
            % display global vars
            app.editStrainRate.Value = num2str(mglobal.e);% strain rate
            app.editGs.Value = num2str(mglobal.G*1000);% thermal gradient
            app.editTm.Value = num2str(mglobal.Ti); % adiabatic temp
            app.editTs.Value = num2str(mglobal.Ts);% surface temp
            app.editP0.Value = num2str(mglobal.P0);% surface pressure
            app.editGravity.Value = num2str(mglobal.g);% gravity
        end
        %%

        function populateLayer(app)
            ilay = find(strcmp(app.listboxLayers.Value, app.listboxLayers.Items));
            model = app.model;
            app.popupmenuRockType.Value = app.popupmenuRockType.Items(model(ilay).irock);
            app.editThickness.Value = num2str(model(ilay).thick);
            %            app.checkboxHydrostatic.Value = num2str(1*(model(ilay).pf=='p'));
            if app.model(ilay).pf=='p'
                app.checkboxHydrostatic.Value = true;
                app.editPf.Value = ' ';
                app.editPf.Enable = 'off';
            else
                app.checkboxHydrostatic.Value = false;
                app.editPf.Value = num2str(model(ilay).pf);
                app.editPf.Enable = 'on';
            end
            % rock type specific
            irk = model(ilay).irock(1);
            rock=app.rock;
            % Populate rheology
            nrhl = size(rock(irk).rheol,2);
            rhlnom = string([]);
            for i = 1:nrhl
                rhlnom(i) = sprintf('%-10s-%s',rock(irk).rheol(i).name,rock(irk).rheol(i).ref);%i+"- "+rock(irk).rheol(i).ref;
                %     contained = ~isempty(find(model(ilay).rock(1).irheol==i));
            end
            app.listboxRheology.Items = rhlnom;
            %app.listboxRheology.Max = nrhl;
            %app.listboxRheology.Max = numel(app.listboxBrittle.Items);

            % Populate grain size
            if (numel(rock(irk).piezo) == false)
                app.popupmenuGrainSize.Items = "input";
            else
                app.popupmenuGrainSize.Items = ["input",{rock(irk).piezo.ref}];
            end
            app.popupmenuGrainSize.Value = app.popupmenuGrainSize.Items{model(ilay).rock(1).gc+1};
            if (model(ilay).rock(1).gc == false)
                app.editGrainSize.Value = num2str(model(ilay).rock(1).gs);
                app.editGrainSize.Enable = 'on';
            else
                app.editGrainSize.Value = 'N/A';
                app.editGrainSize.Enable = 'off';
            end

            % select rheology
            rh=model(ilay).rock(1).irheol;
            iduct=rh(rh>0);
            ibrit=rh(rh<0);
            app.listboxBrittle.Value = app.listboxBrittle.Items(-ibrit);
            app.listboxRheology.Value = app.listboxRheology.Items(iduct);

        end
        %%
        %function popupmenu6_CreateFcn(app,src,event)
        %         function popupmenu6_CreateFcn(app)
        %             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
        %                 set(hObject,'BackgroundColor','red');
        %             end
        %         end
        %%
        function mglobal = queryGlobal(app)
            % Read information on GUI related to global parameters
            mglobal = app.modelGlobal;
            mglobal.e = str2double(app.editStrainRate.Value);
            mglobal.G = str2double(app.editGs.Value)/1000; %convert to K/m)
            mglobal.Ti = str2double(app.editTm.Value);
            mglobal.Ts = str2double(app.editTs.Value);
            mglobal.P0 = str2double(app.editP0.Value);
            mglobal.g = str2double(app.editGravity.Value);
            mglobal.thid = find(strcmp(app.popupmenuTemperature.Value,app.popupmenuTemperature.Items));
            mglobal.ip = find(strcmp(app.popupmenuPlanet.Value,app.popupmenuPlanet.Items));
            mglobal.did = find(strcmp(app.popupmenuDID.Value,app.popupmenuDID.Items));

            if mglobal.thid==3
                mglobal.Tfile=app.popupmenuTemperatureFile.Value;
            end
        end
        %%

        function model = queryLayer(app)
            %load rock.mat
            rock = app.rock; % Need to access rock information
            ilay = find(strcmp(app.listboxLayers.Value, app.listboxLayers.Items));%app.listboxLayers.Items; %Layer to change
            % disp(sprintf('Changing values Layer %g',ilay));

            model = app.model;
            % rock type,  thickness
            model(ilay).irock = find(strcmp(app.popupmenuRockType.Value, app.popupmenuRockType.Items));%app.popupmenuRockType.Items;
            model(ilay).thick = str2double(app.editThickness.Value);
            % Query ductile rheologies (>0) if included

            if (app.checkboxNoDuctile.Value == false)
                iduct=multiMatch(app.listboxRheology.Value, app.listboxRheology.Items);%get(app.listboxRheology.Items);
            else
                iduct=[];
            end
            % Query brittle rheologies (?0)

            ibrit=multiMatch(app.listboxBrittle.Value, app.listboxBrittle.Items);%app.listboxBrittle.Value;%get(app.listboxBrittle.Items);
            % Assemble rheologies
            model(ilay).rock(1).irheol=[iduct,((-1)*ibrit)];%[iduct,(-1)*ibrit];
            % pressure
            if (app.checkboxHydrostatic.Value)
                model(ilay).pf = 'p';
            else
                model(ilay).pf = str2double(app.editPf.Value);
            end
            % grain dependence
            if strcmp(app.popupmenuGrainSize.Value,'input')
                % Custom grain size
                model(ilay).rock(1).gc = 0;
                model(ilay).rock(1).gs = str2double(app.editGrainSize.Value);
            else
                % piezometer
                model(ilay).rock(1).gc = find(strcmp(app.popupmenuGrainSize.Value, app.popupmenuGrainSize.Items)) - 1;
                model(ilay).rock(1).gs = 0;
            end
        end
        %%
        function newhandles = readfile(app, fname)
            % objective: READS INPUT FILE and display on GUI
            [app.model,app.modelGlobal]=parse_script(fname);
            model = app.model;
            nlay = size(app.model,2);
            for i = 1:nlay
                lyrnom(i) = "Layer " + i;
            end
            app.listboxLayers.Items = lyrnom;
            app.pushbuttonChange.Text = 'Save Change';
            app.pushbuttonChange.Enable= 'off';
            app.globalDiscrepant = 0;
            newhandles = app;
        end
        %%
        function setGlobalDiscrepant(app,event)
            % setup warning to highlight discrepancy GUI and model
            app.pushbuttonChange.Text = 'Changes UNSAVED';
            app.pushbuttonChange.Enable= 'on';
            app.pushbuttonExportProfile.Enable= 'off';
            app.pushbuttonPlanetDefault.Enable= 'on';
            app.pushbuttonDrawStructure.Enable= 'on';
            app.globalModel.globalDiscrepant = 1;
        end
        %%
        function setGlobalNonDiscrepant(app)
            % setup warning to highlight discrepancy GUI and model
            app.pushbuttonChange.Text = 'Changes Saved ? Strength profile discrepant';
            app.pushbuttonChange.Enable= 'off';
            app.pushbuttonExportProfile.Enable= 'off';
            app.axesStrength.InnerPosition(4)=app.axesStructure.InnerPosition(4);
            app.axesStrength.InnerPosition(2)=app.axesStructure.InnerPosition(2);
            app.axesStrength.OuterPosition(4)=app.axesStructure.OuterPosition(4);
            app.axesStrength.OuterPosition(2)=app.axesStructure.OuterPosition(2);
            app.axesStrength.Color = '[1,1,1]*.75';
            app.axesTemperature.Color = '[1,1,1]*.75';
            app.axesGrainsize.Color = '[1,1,1]*.75';
            app.pushbuttonDrawStructure.Enable = 'off';
            app.globalDiscrepant = 0;
        end
        %         function RHEOL_GUI_OpeningFcn(app, varargin)
        %             % Ensure that the app appears on screen when run
        %             movegui(app.figure, 'onscreen');
        %
        %             % Create GUIDE-style callback args - Added by Migration Tool
        %             %        [hObjectdata, app] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
        %             % This function has no output args, see OutputFcn.
        %             % Initialize available planets
        %             %load('planet');  app.planet=planet; app.nplanets=nplanets;
        %             load ./planet;  app.planet=planet; app.nplanets=nplanets;
        %             app.popupmenuPlanet.Value = {planet.name,'Custom'};
        %             %            set(app.popupmenuPlanet,'String',{planet.name,'Custom'});
        %             %           set(app.popupmenuTemperature,'String',["Linear","Error function","Custom file"]);
        %             %           set(app.popupmenuDID,'String',["Compression","Extension","Strike-slip"]);
        %             % initialize available rock types
        %             load ./rock; app.rock=rock; app.nrock=nrock;
        %             %            set(app.popupmenuRockType,'String',{rock.name});
        %             %            pushbuttonUpdateList_Callback(app.pushbuttonUpdateListdata, app);
        %             % load default input file
        %             indef = 'Default.rhl';
        %             %            defix = find(strcmp(app.popupmenuInput.String,indef)); %ID of default among current files
        %             if isempty(defix); defix=1; end; %Default to file #1 is default not found
        %             indef=app.popupmenuInput.String{defix};
        %             [Fpath,Froot,Fext] = fileparts(indef);
        %             % handles.flNames = fileNames;
        %             app = readfile(app, indef);
        %             populateGlobal(app);
        %             app.listboxLayers.Value = '1';
        %             listboxLayers_Callback(app.listboxLayersdata, app);
        %
        %             app.popupmenuInput.Value = 'defix'; %highligh default file
        %             app.editGraphic.Value = 'Froot'; %highligh default file
        %
        %             % set default output file
        %             outdef = app.editOutput.String;        % default output file
        %
        %             pushbuttonDrawStructure_Callback(app.pushbuttonDrawStructuredata, app);
        %             pushbuttonDrawTemperature_Callback(app.pushbuttonDrawTemperaturedata, app);
        %             pushbuttonDrawStrength_Callback(app.pushbuttonDrawStrengthdata, app);
        %
        %             app.pushbuttonExportTable.Enable = 'off';
        %             app.pushbuttonExportRheology.Enable = 'off';
        %             app.pushbuttonExportProfile.Enable = 'on';
        %
        %             % Choose default command line output for RHEOL_GUI
        %             app.output = hObject;
        %
        %             % Update handles structure
        %             %guidata(hObject, app);
        %         end


    end

    % end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function RHEOL_GUI_OpeningFcn(app, event, varargin)
              %suppress warnings
warning('off','all');
warning;
            
            % Ensure that the app appears on screen when run
            movegui(app.figure, 'onscreen');

       
            % Initialize available planets
            load planet.mat;  app.planet=planet; app.nplanets=nplanets;
            %app.popupmenuPlanet.ItemsData = {planet.name,'Custom'};
            app.popupmenuPlanet.Items = {planet.name};%{'Mercury', 'Venus', 'Earth'};
            %  app.popupmenuPlanet.Value = planet(3).name; %this is the "default planet that shows up
            app.popupmenuTemperature.Items = {'Linear','Error function','Custom file'};

            app.popupmenuDID.Items = {'Compression','Extension','Strike-slip'};
            %set(app.popupmenuPlanet,'String',{planet.name,'Custom'});
            %set(app.popupmenuTemperature,'String',["Linear","Error function","Custom file"]);
            %set(app.popupmenuDID,'String',["Compression","Extension","Strike-slip"]);
            % initialize available rock types
            load rock.mat; app.rock=rock; app.nrock=nrock;
            app.popupmenuRockType.Items = {rock.name};
            pushbuttonUpdateList_Callback(app);
            % load default input file
            indef = 'Default.rhl';
            %defix = find(strcmp(app.popupmenuInput.String,indef)); %ID of default among current files
            %if isempty(defix); defix=1; end; %Default to file #1 is default not found
            %indef=app.popupmenuInput.String{defix};
            %[Fpath,Froot,Fext] = fileparts(indef);
            defix = find(strcmp(app.popupmenuInput.Items,indef)); %ID of default among current files
            if isempty(defix); defix=1; end %Default to file #1 is default not found
            indef=app.popupmenuInput.Items{defix};
            [Fpath,Froot,Fext] = fileparts(indef);
            %app.flNames = fileNames;
            app = readfile(app, indef);
            populateGlobal(app);
            %app.listboxLayers.Value = 1;
            listboxLayers_Callback(app);
            app.popupmenuInput.Value = indef; %highlight default file
            app.editGraphic.Value = Froot; %highlight default file

            % set default output file
            outdef = app.editOutput.Value;        % default output file

            pushbuttonDrawStructure_Callback(app);
            pushbuttonDrawTemperature_Callback(app);
            pushbuttonDrawStrength_Callback(app);

            app.pushbuttonExportTable.Enable = 'off';
            app.pushbuttonExportRheology.Enable = 'off';
            app.pushbuttonExportProfile.Enable = 'on';

            % Choose default command line output for RHEOL_GUI
            %app.output = hObject;

            % Update handles structure
            %guidata(hObject, app);
            %    end

        end

        % Value changed function: checkboxHydrostatic
        function checkboxHydrostatic_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            if (app.checkboxHydrostatic.Value == true)
                app.editPf.Enable = 'off';
            else
                app.editPf.Enable= 'on';
            end
            %app.pushbuttonChange.Items = 'Changes UNSAVED';
            app.pushbuttonChange.Text = 'Changes UNSAVED';
            app.pushbuttonChange.Enable = 'on';
            app.pushbuttonExportProfile.Enable = 'off';
            app.globalDiscrepant = 1;
        end

        % Value changed function: checkboxNoDuctile
        function checkboxNoDuctile_Callback(app, event)

            if (app.checkboxNoDuctile.Value == true)
                app.listboxRheology.Enable = 'off';
                app.popupmenuGrainSize.Enable = 'off';
                app.editGrainSize.Enable = 'off';
            else
                app.listboxRheology.Enable = 'on';
                app.popupmenuGrainSize.Enable = 'on';
                app.editGrainSize.Enable = 'on';
            end
            app.pushbuttonChange.Text = 'Changes UNSAVED';
            app.pushbuttonChange.Enable = 'on';
            app.pushbuttonExportProfile.Enable = 'off';
        end

        % Value changed function: editGrainSize
        function editGrainSize_Callback(app, event)

            app.editGrainSize.Enable = 'on';
            app.editGrainSize.Value = '1e-2';
            % 
            app.pushbuttonChange.Text = 'Changes UNSAVED';
            app.pushbuttonChange.Enable = 'on';
            app.pushbuttonExportProfile.Enable = 'off';

        end

        % Value changed function: editGravity
        function editGravity_Callback(app, event)

            setGlobalDiscrepant(app,event);
        end

        % Value changed function: editGs
        function editGs_Callback(app, event)


            mglobal = app.modelGlobal;
            mglobal.G = str2double(app.editGs.Value)/1000; %convert to K/m)
            app.editGs.Enable = 'on';
            app.editGs.Value = '1e-2';
            % 
            app.pushbuttonChange.Text = 'Changes UNSAVED';
            app.pushbuttonChange.Enable = 'on';
            app.pushbuttonExportProfile.Enable = 'off';
            setGlobalDiscrepant(app, event);
        end

        % Value changed function: editP0
        function editP0_Callback(app, event)

            setGlobalDiscrepant(app, event);
            app.popupmenuPlanet.Items = numel(get(app.popupmenuPlanet.Value));
        end

        % Value changed function: editPf
        function editPf_Callback(app, event)

            app.pushbuttonChange.Text = 'Changes UNSAVED';
            %app.pushbuttonChange.Editable = 'on';
            app.pushbuttonChange.Enable = 'on';
            app.pushbuttonExportProfile.Enable = 'off';
            app.globalDiscrepant = 1;

        end

        % Value changed function: editStrainRate
        function editStrainRate_Callback(app, event)
          % Read information on GUI related to global parameters
            mglobal = app.modelGlobal;
            mglobal.e = str2double(app.editStrainRate.Value);
            mglobal.G = str2double(app.editGs.Value)/1000; %convert to K/m)
            mglobal.Ti = str2double(app.editTm.Value);
            mglobal.Ts = str2double(app.editTs.Value);
            mglobal.P0 = str2double(app.editP0.Value);
            mglobal.g = str2double(app.editGravity.Value);
            mglobal.thid = find(strcmp(app.popupmenuTemperature.Value,app.popupmenuTemperature.Items));
            mglobal.ip = find(strcmp(app.popupmenuPlanet.Value,app.popupmenuPlanet.Items));
            mglobal.did = find(strcmp(app.popupmenuDID.Value,app.popupmenuDID.Items));


            model=queryLayer(app);
            app.model = model;
            model=recalc_model(app.modelGlobal,app.model,app.rock);
            app.model = model;
            pushbuttonDrawStructure_Callback(app);%(app.pushbuttonDrawStructure, app);
            pushbuttonDrawTemperature_Callback(app);%(app.pushbuttonDrawTemperature, app)
            cla(app.axesStrength);cla(app.axesGrainsize);
            app.axesStrength.OuterPosition(4)=app.axesStructure.OuterPosition(4);
            app.axesStrength.OuterPosition(2)=app.axesStructure.OuterPosition(2);
            app.axesGrainsize.OuterPosition(4)=app.axesStructure.OuterPosition(4);
            app.axesGrainsize.OuterPosition(2)=app.axesStructure.OuterPosition(2);
            app.axesStrength.InnerPosition(4)=app.axesStructure.InnerPosition(4);
            app.axesStrength.InnerPosition(2)=app.axesStructure.InnerPosition(2);
            app.axesGrainsize.InnerPosition(4)=app.axesStructure.InnerPosition(4);
            app.axesGrainsize.InnerPosition(2)=app.axesStructure.InnerPosition(2);
            app.pushbuttonChange.Text = 'Changes UNSAVED';
            app.pushbuttonChange.Enable = 'on';
            app.pushbuttonExportProfile.Enable = 'off';
            app.globalDiscrepant = 1;

        end

        % Value changed function: editThickness
        function editThickness_Callback(app, event)

            app.pushbuttonExportTable.Enable = 'off';
            % Read info about current layer
            model=queryLayer(app);
            app.model = model;
            model=recalc_model(app.modelGlobal,app.model,app.rock);
            app.model = model;
            pushbuttonDrawStructure_Callback(app);%(app.pushbuttonDrawStructure, app);
            pushbuttonDrawTemperature_Callback(app);%(app.pushbuttonDrawTemperature, app)
            cla(app.axesStrength);cla(app.axesGrainsize);
            app.axesStrength.OuterPosition(4)=app.axesStructure.OuterPosition(4);
            app.axesStrength.OuterPosition(2)=app.axesStructure.OuterPosition(2);
            app.axesGrainsize.OuterPosition(4)=app.axesStructure.OuterPosition(4);
            app.axesGrainsize.OuterPosition(2)=app.axesStructure.OuterPosition(2);
            app.axesStrength.InnerPosition(4)=app.axesStructure.InnerPosition(4);
            app.axesStrength.InnerPosition(2)=app.axesStructure.InnerPosition(2);
            app.axesGrainsize.InnerPosition(4)=app.axesStructure.InnerPosition(4);
            app.axesGrainsize.InnerPosition(2)=app.axesStructure.InnerPosition(2);
            app.pushbuttonChange.Text = 'Changes UNSAVED';
            app.pushbuttonChange.Enable = 'on';
            app.pushbuttonExportProfile.Enable = 'off';
            app.globalDiscrepant = 1;
        end

        % Value changed function: editTm
        function editTm_Callback(app, event)
  % Read information on GUI related to global parameters
            mglobal = app.modelGlobal;
            mglobal.e = str2double(app.editStrainRate.Value);
            mglobal.G = str2double(app.editGs.Value)/1000; %convert to K/m)
            mglobal.Ti = str2double(app.editTm.Value);
            mglobal.Ts = str2double(app.editTs.Value);
            mglobal.P0 = str2double(app.editP0.Value);
            mglobal.g = str2double(app.editGravity.Value);
            mglobal.thid = find(strcmp(app.popupmenuTemperature.Value,app.popupmenuTemperature.Items));
            mglobal.ip = find(strcmp(app.popupmenuPlanet.Value,app.popupmenuPlanet.Items));
            mglobal.did = find(strcmp(app.popupmenuDID.Value,app.popupmenuDID.Items));


            model=queryLayer(app);
            app.model = model;
            model=recalc_model(app.modelGlobal,app.model,app.rock);
            app.model = model;
            pushbuttonDrawStructure_Callback(app);%(app.pushbuttonDrawStructure, app);
            pushbuttonDrawTemperature_Callback(app);%(app.pushbuttonDrawTemperature, app)
            cla(app.axesStrength);cla(app.axesGrainsize);
            app.axesStrength.OuterPosition(4)=app.axesStructure.OuterPosition(4);
            app.axesStrength.OuterPosition(2)=app.axesStructure.OuterPosition(2);
            app.axesGrainsize.OuterPosition(4)=app.axesStructure.OuterPosition(4);
            app.axesGrainsize.OuterPosition(2)=app.axesStructure.OuterPosition(2);
            app.axesStrength.InnerPosition(4)=app.axesStructure.InnerPosition(4);
            app.axesStrength.InnerPosition(2)=app.axesStructure.InnerPosition(2);
            app.axesGrainsize.InnerPosition(4)=app.axesStructure.InnerPosition(4);
            app.axesGrainsize.InnerPosition(2)=app.axesStructure.InnerPosition(2);
            app.pushbuttonChange.Text = 'Changes UNSAVED';
            app.pushbuttonChange.Enable = 'on';
            app.pushbuttonExportProfile.Enable = 'off';
            app.globalDiscrepant = 1;

            setGlobalDiscrepant(app, event);
        end

        % Value changed function: editTs
        function editTs_Callback(app, event)
  % Read information on GUI related to global parameters
            mglobal = app.modelGlobal;
            mglobal.e = str2double(app.editStrainRate.Value);
            mglobal.G = str2double(app.editGs.Value)/1000; %convert to K/m)
            mglobal.Ti = str2double(app.editTm.Value);
            mglobal.Ts = str2double(app.editTs.Value);
            mglobal.P0 = str2double(app.editP0.Value);
            mglobal.g = str2double(app.editGravity.Value);
            mglobal.thid = find(strcmp(app.popupmenuTemperature.Value,app.popupmenuTemperature.Items));
            mglobal.ip = find(strcmp(app.popupmenuPlanet.Value,app.popupmenuPlanet.Items));
            mglobal.did = find(strcmp(app.popupmenuDID.Value,app.popupmenuDID.Items));


            model=queryLayer(app);
            app.model = model;
            model=recalc_model(app.modelGlobal,app.model,app.rock);
            app.model = model;
            pushbuttonDrawStructure_Callback(app);%(app.pushbuttonDrawStructure, app);
            pushbuttonDrawTemperature_Callback(app);%(app.pushbuttonDrawTemperature, app)
            cla(app.axesStrength);cla(app.axesGrainsize);
            app.axesStrength.OuterPosition(4)=app.axesStructure.OuterPosition(4);
            app.axesStrength.OuterPosition(2)=app.axesStructure.OuterPosition(2);
            app.axesGrainsize.OuterPosition(4)=app.axesStructure.OuterPosition(4);
            app.axesGrainsize.OuterPosition(2)=app.axesStructure.OuterPosition(2);
            app.axesStrength.InnerPosition(4)=app.axesStructure.InnerPosition(4);
            app.axesStrength.InnerPosition(2)=app.axesStructure.InnerPosition(2);
            app.axesGrainsize.InnerPosition(4)=app.axesStructure.InnerPosition(4);
            app.axesGrainsize.InnerPosition(2)=app.axesStructure.InnerPosition(2);
            app.pushbuttonChange.Text = 'Changes UNSAVED';
            app.pushbuttonChange.Enable = 'on';
            app.pushbuttonExportProfile.Enable = 'off';
            app.globalDiscrepant = 1;

            setGlobalDiscrepant(app, event);
        end

        % Value changed function: listboxBrittle
        function listboxBrittle_Callback(app, event)

            app.pushbuttonChange.Text = 'Changes UNSAVED';
            app.pushbuttonChange.Enable = 'on';
            app.pushbuttonExportProfile.Enable = 'off';
            app.globalDiscrepant = 1;
        end

        % Value changed function: listboxLayers
        function listboxLayers_Callback(app, event)

            populateLayer(app);
        end

        % Value changed function: listboxRheology
        function listboxRheology_Callback(app, event)

            app.pushbuttonChange.Text = 'Changes UNSAVED';
            app.pushbuttonChange.Enable = 'on';
            app.pushbuttonExportProfile.Enable = 'off';
        end

        % Value changed function: listboxRocks
        function listboxRocks_Callback(app, event)

            app.pushbuttonChange.Text = 'Changes UNSAVED';
            app.pushbuttonChange.Enable = 'on';
            app.pushbuttonExportProfile.Enable = 'off';
        end

        % Value changed function: popupmenuDID
        function popupmenuDID_Callback(app, event)

            setGlobalDiscrepant(app, event);
        end

        % Value changed function: popupmenuGrainSize
        function popupmenuGrainSize_Callback(app, event)

             if (app.popupmenuGrainSize.Value == true)
                app.popupmenuGrainSize.Enable = 'on';
                app.editGrainSize.Value = '1e-2';
                 app.editGrainSize.Enable = 'on';
            else
                app.editGrainSize.Enable = 'off';
            end
            app.pushbuttonChange.Text = 'Change (changes unsaved)';
            app.pushbuttonChange.Enable = 'on';

        end

        % Value changed function: popupmenuPlanet
        function popupmenuPlanet_Callback(app, event)

            pushbuttonPlanetDefault_Callback(app);%(app.pushbuttonPlanetDefaultdata, app);
            setGlobalDiscrepant(app, event);
        end

        % Value changed function: popupmenuRockType
        function popupmenuRockType_Callback(app, event)

            rock=app.rock;
            irk = find(strcmp(app.popupmenuRockType.Value, app.popupmenuRockType.Items));
            nrhl = size(rock(irk).rheol,2);
            if nrhl > 0
                % rheologies
                %rhlnom = Text([]);
                rhlnom = cell(1,nrhl);%string([]);
                for i = 1:nrhl
                    rhlnom{i} = sprintf('%-10s-%s',rock(irk).rheol(i).name,rock(irk).rheol(i).ref);%i+"- "+rock(irk).rheol(i).ref;
                end
                %app.listboxRheology.Value = nrhl;
                app.listboxRheology.Items = rhlnom;
                val = multiMatch(app.popupmenuRockType.Value, app.popupmenuRockType.Items);%app.listboxRheology.Value;
                if (max(val) > nrhl)
                    app.listboxRheology.Value = app.listboxRheology.Items{1};
                end
                %app.listboxRheology.Enable = 'on';
                
                %app.listboxRheology.ListboxTop = true;

                % piezometers / grain dependences
                if (numel(rock(irk).piezo) == 0) %no piezometers
                    app.popupmenuGrainSize.Items = {'input'};
                else
                    app.popupmenuGrainSize.Items = [{'input'},{rock(irk).piezo.ref}];
                end
                app.popupmenuGrainSize.Value = app.popupmenuGrainSize.Items{1};
                %app.popupmenuGrainSize.Enable = 'on';
                app.editGrainSize.Value = '1e-2';
                %app.editGrainSize.Enable = 'on';
                app.checkboxNoDuctile.Value = 0;
                %app.pushbuttonChange.Text = 'Change (changes unsaved)';
                %app.pushbuttonChange.Enable = 'on';
                
            else
                app.listboxRheology.Items = {''};
                %app.listboxRheology.Enable = 'off';
                app.popupmenuGrainSize.Items = {''};
                %app.popupmenuGrainSize.Enable = 'off';
                app.editGrainSize.Value = '';
                %app.editGrainSize.Enable = 'off';
                app.checkboxNoDuctile.Value = 1;
            end
            checkboxNoDuctile_Callback(app);


        end

        % Value changed function: popupmenuTemperatureFile
        function popupmenuTemperatureFile_Callback(app, event)

            setGlobalDiscrepant(app, event);
        end

        % Callback function: popupmenuTemperature, uipanelFile
        function popupmenuTemperature_Callback(app, event)
   %suppress warnings
warning('off','all');
warning;
            if app.popupmenuTemperature.Items{3} %hObject.Value==3
                app.popupmenuTemperatureFile.Enable = 'on';
                xfiles = struct2cell(dir('*.thm'));
                fileNames = xfiles(1,:)';
                app.flNames = fileNames;
                app.popupmenuTemperatureFile.Items = fileNames;
                indef = 'Default.thm';
                defix = find(strcmp(app.popupmenuTemperatureFile.Value, app.popupmenuTemperatureFile.Items));
                %defix = find(strcmp(app.popupmenuTemperatureFile.Value,indef)); %ID of default among current files
                if isempty(defix); defix=1; end %Default to file #1 is default not found
                %indef=app.popupmenuTemperatureFile{defix};
                %indef=app.popupmenuTemperatureFile.String{defix};
                %app.popupmenuTemperatureFile.Items={defix};
                app.editTs.Enable = 'off';
                app.editTm.Enable = 'off';
                app.editGs.Enable = 'off';
            else
                app.popupmenuTemperatureFile.Enable = 'off';
                app.editTs.Enable = 'on';
                app.editTm.Enable = 'on';
                app.editGs.Enable = 'on';
            end
            setGlobalDiscrepant(app);
            app.globalDiscrepant = 1;

        end

        % Button pushed function: pushbuttonAddLayer
        function pushbuttonAddLayer_Callback(app, event)
            %irk = find(strcmp(app.popupmenuRockType.Value, app.popupmenuRockType.Items));
            %nrhl = size(rock(irk).rheol,2);
            %ix = app.listboxLayers.Value
            ix = find(strcmp(app.listboxLayers.Value, app.listboxLayers.Items));
            lst = app.listboxLayers.Items;
            n = numel(lst);
            %n = size(lst,1);
            model = app.model;
             if ix==n
                 in=n+1;
                 model(in)=model(n);
             else
                %for in=n:-1:ix
                for in=n:-1:ix
                    model(in+1)=model(in);
                    %lst{in+1}=lst{in};
                    lst{in+1}=sprintf('Layer %i', in+1);%"Layer "+(in+1); 
                    %ix{in+1}="Layer "+(in+1);
                end
            end
            lst{in}=sprintf('Layer %i', in);%"Layer "+(in+1);
            app.listboxLayers.Items = lst;
            app.listboxLayers.Value = lst{in};
            %app.listboxLayers.Items = lst(1,1:in+1);%lst{in};
            %app.listboxLayers.Items = lst{:};
            %app.listboxLayers.Value = app.listboxLayers.Items{in};
            %lst = "Layer" + (in);
            % update layers in model
            app.model = model;
            populateLayer(app);
            model=recalc_model(app.modelGlobal,app.model,app.rock);
            app.model = model;
            pushbuttonDrawStructure_Callback(app);%(app.pushbuttonDrawStructure, app);
            pushbuttonDrawTemperature_Callback(app);%(app.pushbuttonDrawTemperature, app);
            cla(app.axesStrength);cla(app.axesGrainsize);
            %axes tight;
            app.axesStrength.InnerPosition(4)=app.axesStructure.InnerPosition(4);
            app.axesStrength.InnerPosition(2)=app.axesStructure.InnerPosition(2);
            app.axesGrainsize.InnerPosition(4)=app.axesStructure.InnerPosition(4);
            app.axesGrainsize.InnerPosition(2)=app.axesStructure.InnerPosition(2);
            app.axesGrainsize.InnerPosition(4)=app.axesTemperature.InnerPosition(4);
            app.axesGrainsize.InnerPosition(2)=app.axesTemperature.InnerPosition(2);
            app.axesStrength.OuterPosition(3)=app.axesStructure.OuterPosition(3);
            app.axesGrainsize.OuterPosition(3)=app.axesStructure.OuterPosition(3);
            pushbuttonDrawStrength_Callback(app);
            app.pushbuttonChange.Text = 'Select rock and rheology or Save';
            app.pushbuttonChange.Enable = 'on';
        end

        % Button pushed function: pushbuttonAddRock
        function pushbuttonAddRock_Callback(app, event)

            app.pushbuttonChange.Text = 'Changes UNSAVED';
            app.pushbuttonChange.Enable = 'on';
            app.pushbuttonExportProfile.Enable = 'off';
        end

        % Button pushed function: pushbuttonChange
        function pushbuttonChange_Callback(app, event)

            app.pushbuttonExportTable.Enable = 'off';
            % Update global, if needed
            if app.globalDiscrepant
                mglobal=queryGlobal(app);
                app.modelGlobal = mglobal;
                setGlobalNonDiscrepant(app);
            else
                mglobal=app.modelGlobal;
            end
            % Read info about current layer
            model=queryLayer(app);
            app.model = model;
            % Rework all layers
            model=recalc_model(mglobal,model,app.rock);
            app.model = model;
            % Update graphs
            pushbuttonDrawStructure_Callback(app);%(app.pushbuttonDrawStructuredata, app);
            pushbuttonDrawTemperature_Callback(app);%(app.pushbuttonDrawTemperature, app);
            pushbuttonDrawStrength_Callback(app);%(app.pushbuttonDrawStrength, app);
            % Adapt buttons
            app.pushbuttonExportProfile.Enable = 'on';
            app.pushbuttonChange.Text = 'Change';
            app.pushbuttonChange.Enable = 'off';

            %guidata(hObject, app);
        end

        % Button pushed function: pushbuttonDeleteLayer
        function pushbuttonDeleteLayer_Callback(app, event)
            ix = find(strcmp(app.listboxLayers.Value, app.listboxLayers.Items));
            %ix = app.listboxLayers.Items;
            lst = app.listboxLayers.Items;
            n = numel(lst);
            %n = size(ix,1);
            %n = size(lst,1);
            model = app.model;
            for in=ix:n-1
                lst{in}=sprintf('Layer %i', in);%"Layer"+(in);
            end
            app.listboxLayers.Items = [lst(1:n-1)];
            app.listboxLayers.Value = app.listboxLayers.Items{max(ix-1,1)};
            % remove model(ix) from model
            %app.model = model;
            model = app.model;
            model = model([1:ix-1,ix+1:end]);
            % update layers in model
            model=recalc_model(app.modelGlobal,model,app.rock);
            app.model = model;
            populateLayer(app);
            %update structure and temperature
            app.model = model;
            listboxLayers_Callback(app);%(app.listboxLayersdata, app);
            pushbuttonDrawStructure_Callback(app);%(app.pushbuttonDrawStructure, app);
            pushbuttonDrawTemperature_Callback(app);%(app.pushbuttonDrawTemperature, app);
            cla(app.axesStrength);cla(app.axesGrainsize);
            app.axesStrength.InnerPosition(4)=app.axesStructure.InnerPosition(4);
            app.axesStrength.InnerPosition(2)=app.axesStructure.InnerPosition(2);
            app.axesGrainsize.InnerPosition(4)=app.axesStructure.InnerPosition(4);
            app.axesGrainsize.InnerPosition(2)=app.axesStructure.InnerPosition(2);
            app.axesStrength.OuterPosition(3)=app.axesStructure.OuterPosition(3);
            app.axesGrainsize.OuterPosition(3)=app.axesStructure.OuterPosition(3);
            app.axesGrainsize.InnerPosition(4)=app.axesTemperature.InnerPosition(4);
            app.axesGrainsize.InnerPosition(2)=app.axesTemperature.InnerPosition(2);
            app.pushbuttonChange.Text = 'Changes UNSAVED';
            app.pushbuttonChange.Enable = 'on';
            app.pushbuttonExportProfile.Enable = 'off';
            app.globalDiscrepant = 1;
            app.model = model;
            %guidata(hObject, app);
        end

        % Button pushed function: pushbuttonDeleteRock
        function pushbuttonDeleteRock_Callback(app, event)

            app.pushbuttonChange.Text = 'Changes UNSAVED';
            app.pushbuttonChange.Enable = 'on';
            app.pushbuttonExportProfile.Enable = 'off';
        end

        % Button pushed function: pushbuttonDrawStrength
        function pushbuttonDrawStrength_Callback(app, event)

            % Draw strength profile and grain size profile
            app.model=calc_profile(app.model,app.modelGlobal.did,app.modelGlobal.e,app.rock);
            viz_strength(app.model,app.modelGlobal.did,app.modelGlobal.e,...
                app.axesStrength,app.axesGrainsize,app.axesTemperature,app.rock);
            app.axesTemperature.Color = 'none';
            app.axesGrainsize.Color = 'white';
            app.pushbuttonExportProfile.Enable = 'on';
            app.axesStrength.InnerPosition(4)=app.axesStructure.InnerPosition(4);
            app.axesStrength.InnerPosition(2)=app.axesStructure.InnerPosition(2);
            app.axesGrainsize.InnerPosition(4)=app.axesStructure.InnerPosition(4);
            app.axesGrainsize.InnerPosition(2)=app.axesStructure.InnerPosition(2);
            app.axesTemperature.InnerPosition(4)=app.axesStructure.InnerPosition(4);
            app.axesTemperature.InnerPosition(2)=app.axesStructure.InnerPosition(2);
            app.axesStrength.OuterPosition(3)=app.axesStructure.OuterPosition(3);
            app.axesGrainsize.OuterPosition(3)=app.axesStructure.OuterPosition(3);
            app.axesTemperature.OuterPosition(3)=app.axesStructure.OuterPosition(3);
            app.axesGrainsize.InnerPosition(4)=app.axesTemperature.InnerPosition(4);
            app.axesGrainsize.InnerPosition(2)=app.axesTemperature.InnerPosition(2);

            %guidata(hObject,app);
        end

        % Button pushed function: pushbuttonDrawStructure
        function pushbuttonDrawStructure_Callback(app, event)

            % represents schematically the current structure
            axes(app.axesStructure);
            model = app.model;
            cla;
            rectangle('Position',[0,0,1,model(length(model)).zbot/1000]);
            %rectangle('Position',[0,-model(length(model)).zbot/1000,1,model(length(model)).zbot/1000]);

            %             positionVector = [0,model(length(model)).zbot/1000,1,model(length(model)).zbot/1000];
            %             rectangle('Position',positionVector);
            colmap = colorcube(app.nrock);
            for j = 1:length(model)
                rectangle('Position',[0.1,model(j).ztop/1000,0.8,model(j).thick/1000],...
                    'FaceColor',colmap(model(j).irock,:),'Curvature',[0.1,0.2]);
            end
            set(gca,'xlim',[0,1],'box','on','xtick','','ydir','reverse','color','white');
            ylabel('Depth (km)');
        end

        % Button pushed function: pushbuttonDrawTemperature
        function pushbuttonDrawTemperature_Callback(app, event)

            % Draws temperature profile
            axes(app.axesTemperature);
            model = app.model;
            cla(app.axesTemperature,'reset');hold on;
            colmap =  colorcube(app.nrock);
            for il = 1:length(model);
                z=linspace(model(il).ztop,model(il).zbot,100);
                T=model(il).Temperature(z)-app.modelGlobal.Celsius;
                plot(T,z/1000,'linewidth',2,'parent',app.axesTemperature,...
                    'Color',colmap(model(il).irock,:));
            end
            set(gca,'box','on','ydir','reverse','color','white','xaxislocation','top');
            xlabel(app.axesTemperature,'Temperature (°C)','FontSize',12.5);
            app.axesTemperature.InnerPosition(4)=app.axesStructure.InnerPosition(4);
            app.axesTemperature.OuterPosition(3)=app.axesStructure.OuterPosition(3);
            app.axesTemperature.InnerPosition(2)=app.axesStructure.InnerPosition(2);
            app.axesGrainsize.InnerPosition(4)=app.axesTemperature.InnerPosition(4);
            app.axesGrainsize.InnerPosition(2)=app.axesTemperature.InnerPosition(2);
        end

        % Button pushed function: pushbuttonEffectiveRheology
        function pushbuttonEffectiveRheology_Callback(app, event)

            app.pushbuttonEffectiveRheology.Enable = 'off';
            %fix        app.pushbuttonExportTable.Enable = 'off','String','Working !!!';
            % Strain rates to consider
            Eall=10.^(-20:0.5:-8);
            nE=numel(Eall);
            Sall=NaN(size(Eall));
            hw=waitbar(0,'Effective rheology calculation in progress');
            for iE=1:nE
                e=Eall(iE);
                %disp(sprintf('Working E=%s 1/s',e));
                waitbar((iE-1)/nE,hw);
                app.model=calc_profile(app.model,app.modelGlobal.did,e,app.rock);
                Sall(iE)=integrate_profile(app.model,e);
            end
            close(hw);
            set(0,'DefaultAxesLineWidth',1,...
                'DefaultAxesFontSize',12,...
                'DefaultAxesColor','none');
            axes(app.axesStrength);  cla; hold on;
            app.axesStrength.InnerPosition(4)=app.axesStructure.InnerPosition(4);
            app.axesStrength.OuterPosition(3)=app.axesStructure.OuterPosition(3);
            app.axesStrength.InnerPosition(2)=app.axesStructure.InnerPosition(2);
            app.axesGrainsize.InnerPosition(4)=app.axesTemperature.InnerPosition(4);
            app.axesGrainsize.InnerPosition(2)=app.axesTemperature.InnerPosition(2);
            loglog(Eall,Sall/1e6/1e3,'linewidth',2);
            xlabel('Strain Rate (1/s)','fontsize',18);
            ylabel('Integrated stress (MPa*km)','fontsize',18);
            axis(10.^[-20,-8,2,5]);
            set(gca,'xscale','log','yscale','log','color','white','box','on',...
                'ydir','normal');
            app.pushbuttonEffectiveRheology.Enable = 'on';
            app.pushbuttonExportTable.Enable = 'on';
            app.pushbuttonExportTable.Text ='Export rheology table';
            app.pushbuttonExportRheology.Enable = 'on';
        end

        % Button pushed function: pushbuttonExportModel
        function pushbuttonExportModel_Callback(app, event)

            Rt=app.editGraphic.Value;
            model = app.model;
            save(sprintf('%s.mat',Rt),"model");
        end

        % Button pushed function: pushbuttonExportProfile
        function pushbuttonExportProfile_Callback(app, event)

            Rt=app.editGraphic.Value;

            ProfileFigureExport(1,sprintf('%s_profile.pdf',Rt),...
                app.axesStrength,app.axesTemperature,app.axesGrainsize);

        end

        % Button pushed function: pushbuttonExportRheology
        function pushbuttonExportRheology_Callback(app, event)

            Rt=app.editGraphic.Value;
            RheologyFigureExport(2,sprintf('%s_rheology.pdf',Rt),...
                app.axesStrength);
            app.axesStrength.InnerPosition(4)=app.axesStructure.InnerPosition(4);
            app.axesStrength.InnerPosition(2)=app.axesStructure.InnerPosition(2);
            app.axesGrainsize.InnerPosition(4)=app.axesTemperature.InnerPosition(4);
            app.axesGrainsize.InnerPosition(2)=app.axesTemperature.InnerPosition(2);
        end

        % Button pushed function: pushbuttonExportTable
        function pushbuttonExportTable_Callback(app, event)

            Rt=app.editGraphic.Value;
            Mall=[get(get(app.axesStrength,'children'),'xdata');...
                get(get(app.axesStrength,'children'),'ydata')]';
            app.axesStrength.InnerPosition(4)=app.axesStructure.InnerPosition(4);
            app.axesStrength.OuterPosition(3)=app.axesStructure.OuterPosition(3);
            app.axesStrength.InnerPosition(2)=app.axesStructure.InnerPosition(2);
            app.axesGrainsize.InnerPosition(4)=app.axesTemperature.InnerPosition(4);
            app.axesGrainsize.InnerPosition(2)=app.axesTemperature.InnerPosition(2);
            save(sprintf('%s_rheology.rht',Rt),'Mall','-ascii');
        end

        % Button pushed function: pushbuttonPlanetDefault
        function pushbuttonPlanetDefault_Callback(app, event)

            %app.GlobalDiscrepant;
            setGlobalDiscrepant(app);
            planet=app.planet;
            ip = find(strcmp(app.popupmenuPlanet.Value, app.popupmenuPlanet.Items));
            %ip = app.popupmenuPlanet.Value;
            if  ip%<numel(app.popupmenuPlanet.Value)
                Ts=planet(ip).env.Ts;
                Ti=planet(ip).env.Ti;
                G=planet(ip).env.G;
                P0=planet(ip).env.P0;
                g=planet(ip).global.gravity;

                app.editGs.Value = num2str(G);
                app.editTm.Value = num2str(Ti);
                app.editTs.Value = num2str(Ts);
                app.editP0.Value = num2str(P0);
                app.editGravity.Value = num2str(g);
            end
            app.pushbuttonPlanetDefault.Enable = 'off';
        end

        % Button pushed function: pushbuttonRead
        function pushbuttonRead_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            %        [hObjectdata, app] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % Read information from popup menu Input
            %fsb_ix = app.popupmenuInput.Value;
            %fsb_fls = Text(app.popupmenuInput.Value);
            %5fsb_fls = app.popupmenuInput.Value;
            %fsb_ix = get(app.popupmenuInput,'Value');
            %fsb_fls = string(get(app.popupmenuInput,'String'));
            % get filename, parse root, and assigne to Graphic text edit box
            %fileName = fsb_fls(fsb_ix);
            fileName = app.popupmenuInput.Value;
            [Fpath,Froot,Fext] = fileparts(fileName);
            app.editGraphic.Value = Froot;
            % Read information
            app = readfile(app, char(fileName));
            populateGlobal(app);
            %app.listboxLayers.Value = true;
            pushbuttonUpdateList_Callback(app);%(app.pushbuttonUpdateListdata, app);
            % Run model
            pushbuttonDrawStructure_Callback(app);%(app.pushbuttonDrawStructuredata, app);
            pushbuttonDrawTemperature_Callback(app);%(app.pushbuttonDrawTemperaturedata, app);
            pushbuttonDrawStrength_Callback(app);%(app.pushbuttonDrawStrengthdata, app);
        end

        % Button pushed function: pushbuttonUpdateList
        function pushbuttonUpdateList_Callback(app, event)

            % find all input files and display them in input menu
            xfiles = struct2cell(dir('*.rhl'));
            fileNames = xfiles(1,:)';
            app.flNames = fileNames;
            app.popupmenuInput.Items = fileNames;

        end

        % Button pushed function: pushbuttonWrite
        function pushbuttonWrite_Callback(app, event)

            % Read text in Output text box
            fileName = app.editOutput.Value;
            %create file if necessary
            if (exist('fileName')==false)
                fileName = 'out_file.txt';
            end
            % save information
            save_script(fileName,app.model,app.modelGlobal)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure = uifigure('Visible', 'off');
            app.figure.Name = 'RHEOL_GUI';
            app.figure.Tag = 'figure1';
            app.uipanelGlobal = uipanel(app.figure);
            app.uipanelGlobal.Title = 'Global Parameters';
            app.uipanelGlobal.Tag = 'uipanelGlobal';
            app.editStrainRate = uieditfield(app.uipanelGlobal, 'text');
            app.editStrainRate.ValueChangedFcn = createCallbackFcn(app, @editStrainRate_Callback, true);
            app.editStrainRate.Tag = 'editStrainRate';
            app.editStrainRate.Value = 'Edit Text';
            app.strainRateLabel = uilabel(app.uipanelGlobal);
            app.strainRateLabel.Tag = 'text2';
            app.strainRateLabel.Text = 'Strain Rate (1/s)';
            app.editTs = uieditfield(app.uipanelGlobal, 'text');
            app.editTs.ValueChangedFcn = createCallbackFcn(app, @editTs_Callback, true);
            app.editTs.Tag = 'editTs';
            app.editTs.Value = 'Edit Text';
            app.editGs = uieditfield(app.uipanelGlobal, 'text');
            app.editGs.ValueChangedFcn = createCallbackFcn(app, @editGs_Callback, true);
            app.editGs.Tag = 'editGs';
            app.editGs.Value = 'Edit Text';
            app.surfaceTLabel = uilabel(app.uipanelGlobal);
            app.surfaceTLabel.Tag = 'text3';
            app.surfaceTLabel.Text = 'Surface temperature (K)';
            app.TGradientLabel = uilabel(app.uipanelGlobal);
            app.TGradientLabel.Tag = 'text4';
            app.TGradientLabel.Text = 'Temperature gradient (K/m)';
            app.popupmenuPlanet = uidropdown(app.uipanelGlobal);
            app.popupmenuPlanet.Items = {'planet'};
            app.popupmenuPlanet.ValueChangedFcn = createCallbackFcn(app, @popupmenuPlanet_Callback, true);
            app.popupmenuPlanet.Tag = 'popupmenuPlanet';
            app.popupmenuPlanet.Value = 'planet';
            app.pushbuttonPlanetDefault = uibutton(app.uipanelGlobal, 'push');
            app.pushbuttonPlanetDefault.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonPlanetDefault_Callback, true);
            app.pushbuttonPlanetDefault.Tag = 'pushbuttonPlanetDefault';
            app.pushbuttonPlanetDefault.Text = 'Reset to Planet Default';
            app.popupmenuTemperature = uidropdown(app.uipanelGlobal);
            app.popupmenuTemperature.Items = {'Temperature Profile'};
            app.popupmenuTemperature.ValueChangedFcn = createCallbackFcn(app, @popupmenuTemperature_Callback, true);
            app.popupmenuTemperature.Tag = 'popupmenuTemperature';
            app.popupmenuTemperature.Value = 'Temperature Profile';
            app.editTm = uieditfield(app.uipanelGlobal, 'text');
            app.editTm.ValueChangedFcn = createCallbackFcn(app, @editTm_Callback, true);
            app.editTm.Tag = 'editTm';
            app.editTm.Value = 'Edit Text';
            app.adiabaticTLabel = uilabel(app.uipanelGlobal);
            app.adiabaticTLabel.Tag = 'text5';
            app.adiabaticTLabel.Text = 'Adiabatic temperature (K)';
            app.editP0 = uieditfield(app.uipanelGlobal, 'text');
            app.editP0.ValueChangedFcn = createCallbackFcn(app, @editP0_Callback, true);
            app.editP0.Tag = 'editP0';
            app.editP0.Enable = 'off';
            app.editP0.Value = 'Surface pressure';
            app.surfacePressureLabel = uilabel(app.uipanelGlobal);
            app.surfacePressureLabel.Tag = 'text6';
            app.surfacePressureLabel.Text = 'Surface pressure (Pa)';
            app.editGravity = uieditfield(app.uipanelGlobal, 'text');
            app.editGravity.ValueChangedFcn = createCallbackFcn(app, @editGravity_Callback, true);
            app.editGravity.Tag = 'editGravity';
            app.editGravity.Enable = 'off';
            app.editGravity.Value = '9.8';
            app.gravityLabel = uilabel(app.uipanelGlobal);
            app.gravityLabel.Tag = 'text7';
            app.gravityLabel.Text = 'Gravity';
            app.popupmenuDID = uidropdown(app.uipanelGlobal);
            app.popupmenuDID.Items = {'Compression', 'Extension', 'Strike-slip'};
            app.popupmenuDID.ValueChangedFcn = createCallbackFcn(app, @popupmenuDID_Callback, true);
            app.popupmenuDID.Tag = 'popupmenuDID';
            app.popupmenuDID.Value = 'Compression';
            app.popupmenuTemperatureFile = uidropdown(app.uipanelGlobal);
            app.popupmenuTemperatureFile.Items = {'default.thm'};
            app.popupmenuTemperatureFile.ValueChangedFcn = createCallbackFcn(app, @popupmenuTemperatureFile_Callback, true);
            app.popupmenuTemperatureFile.Tag = 'popupmenuTemperatureFile';
            app.popupmenuTemperatureFile.Enable = 'off';
            app.popupmenuTemperatureFile.Value = 'default.thm';
            app.uipanelFile = uipanel(app.figure);
            app.uipanelFile.Title = 'File Control';
            app.uipanelFile.SizeChangedFcn = createCallbackFcn(app, @popupmenuTemperature_Callback, true);
            app.uipanelFile.Tag = 'uipanelFile';
            app.editOutput = uieditfield(app.uipanelFile, 'text');
            app.editOutput.Tag = 'editOutput';
            app.editOutput.Value = 'output.rhl';
            app.outputFileLabel = uilabel(app.uipanelFile);
            app.outputFileLabel.Tag = 'text8';
            app.outputFileLabel.Text = 'Output File Name:';
            app.pushbuttonWrite = uibutton(app.uipanelFile, 'push');
            app.pushbuttonWrite.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonWrite_Callback, true);
            app.pushbuttonWrite.Tag = 'pushbuttonWrite';
            app.pushbuttonWrite.Text = 'Write to File';
            app.popupmenuInput = uidropdown(app.uipanelFile);
            app.popupmenuInput.Items = {'inputfile.rhl'};
            app.popupmenuInput.Tag = 'popupmenuInput';
            app.popupmenuInput.Value = 'inputfile.rhl';
            app.inputFileNameLabel = uilabel(app.uipanelFile);
            app.inputFileNameLabel.Tag = 'text9';
            app.inputFileNameLabel.Text = 'Input File Name:';
            app.pushbuttonRead = uibutton(app.uipanelFile, 'push');
            app.pushbuttonRead.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonRead_Callback, true);
            app.pushbuttonRead.Tag = 'pushbuttonRead';
            app.pushbuttonRead.Text = 'Read from File';
            app.pushbuttonUpdateList = uibutton(app.uipanelFile, 'push');
            app.pushbuttonUpdateList.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonUpdateList_Callback, true);
            app.pushbuttonUpdateList.Tag = 'pushbuttonUpdateList';
            app.pushbuttonUpdateList.Text = 'Update list';
            app.uipanelLayers = uipanel(app.figure);
            app.uipanelLayers.Title = 'Structure';
            app.uipanelLayers.Tag = 'uipanelLayers';
            app.pushbuttonChange = uibutton(app.uipanelLayers, 'push');
            app.pushbuttonChange.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonChange_Callback, true);
            app.pushbuttonChange.Tag = 'pushbuttonChange';
            app.pushbuttonChange.Text = 'Save Changes';
            app.uibuttongroup1 = uibuttongroup(app.uipanelLayers);
            app.uibuttongroup1.Title = 'Layers';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.listboxLayers = uilistbox(app.uibuttongroup1);
            app.listboxLayers.Items = {'Layer1', 'Layer2', 'Layer3', 'Layer4', 'Layer5'};
            app.listboxLayers.ValueChangedFcn = createCallbackFcn(app, @listboxLayers_Callback, true);
            app.listboxLayers.Tag = 'listboxLayers';
            app.listboxLayers.Value = 'Layer1';
            app.pushbuttonAddLayer = uibutton(app.uibuttongroup1, 'push');
            app.pushbuttonAddLayer.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonAddLayer_Callback, true);
            app.pushbuttonAddLayer.Tag = 'pushbuttonAddLayer';
            app.pushbuttonAddLayer.Text = 'Add';
            app.pushbuttonDeleteLayer = uibutton(app.uibuttongroup1, 'push');
            app.pushbuttonDeleteLayer.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonDeleteLayer_Callback, true);
            app.pushbuttonDeleteLayer.Tag = 'pushbuttonDeleteLayer';
            app.pushbuttonDeleteLayer.Text = 'Delete';
            app.editThickness = uieditfield(app.uibuttongroup1, 'text');
            app.editThickness.ValueChangedFcn = createCallbackFcn(app, @editThickness_Callback, true);
            app.editThickness.Tag = 'editThickness';
            app.editThickness.Value = '0.0';
            app.thicknessLabel = uilabel(app.uibuttongroup1);
            app.thicknessLabel.Tag = 'text16';
            app.thicknessLabel.Text = 'Thickness (m)';
            app.editPf = uieditfield(app.uibuttongroup1, 'text');
            app.editPf.ValueChangedFcn = createCallbackFcn(app, @editPf_Callback, true);
            app.editPf.Tag = 'editPf';
            app.editPf.Value = '0';
            app.fluidPressureLabel = uilabel(app.uibuttongroup1);
            app.fluidPressureLabel.Tag = 'text17';
            app.fluidPressureLabel.Text = 'fluid pressure coefficient lambda';
            app.checkboxHydrostatic = uicheckbox(app.uibuttongroup1);
            app.checkboxHydrostatic.ValueChangedFcn = createCallbackFcn(app, @checkboxHydrostatic_Callback, true);
            app.checkboxHydrostatic.Tag = 'checkboxHydrostatic';
            app.checkboxHydrostatic.Text = 'Hydrostatic pressure';
            app.checkboxHydrostatic.Value = true;
            app.uibuttongroup3 = uibuttongroup(app.uibuttongroup1);
            app.uibuttongroup3.Title = 'Rock';
            app.uibuttongroup3.Tag = 'uibuttongroup3';
            app.popupmenuRockType = uidropdown(app.uibuttongroup3);
            app.popupmenuRockType.Items = {'rock type'};
            app.popupmenuRockType.ValueChangedFcn = createCallbackFcn(app, @popupmenuRockType_Callback, true);
            app.popupmenuRockType.Tag = 'popupmenuRockType';
            app.popupmenuRockType.Value = 'rock type';
            app.listboxRocks = uilistbox(app.uibuttongroup3);
            app.listboxRocks.Items = {'1'};
            app.listboxRocks.ValueChangedFcn = createCallbackFcn(app, @listboxRocks_Callback, true);
            app.listboxRocks.Tag = 'listboxRocks';
            app.listboxRocks.Value = '1';
            app.pushbuttonAddRock = uibutton(app.uibuttongroup3, 'push');
            app.pushbuttonAddRock.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonAddRock_Callback, true);
            app.pushbuttonAddRock.Tag = 'pushbuttonAddRock';
            app.pushbuttonAddRock.Text = 'Add';
            app.pushbuttonDeleteRock = uibutton(app.uibuttongroup3, 'push');
            app.pushbuttonDeleteRock.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonDeleteRock_Callback, true);
            app.pushbuttonDeleteRock.Tag = 'pushbuttonDeleteRock';
            app.pushbuttonDeleteRock.Text = 'Delete';
            app.uibuttongroup4 = uibuttongroup(app.uibuttongroup3);
            app.uibuttongroup4.Title = 'Rheology';
            app.uibuttongroup4.Tag = 'uibuttongroup4';
            app.listboxRheology = uilistbox(app.uibuttongroup4);
            app.listboxRheology.Items = {'1 - ', '2 - ', '3 - ', '4 - ', '5 - ', '6 - '};
            app.listboxRheology.Multiselect = 'on';
            app.listboxRheology.ValueChangedFcn = createCallbackFcn(app, @listboxRheology_Callback, true);
            app.listboxRheology.Tag = 'listboxRheology';
            app.listboxRheology.Value = {'1 - '};
            app.popupmenuGrainSize = uidropdown(app.uibuttongroup4);
            app.popupmenuGrainSize.Items = {'grain dependence selection menu'};
            app.popupmenuGrainSize.ValueChangedFcn = createCallbackFcn(app, @popupmenuGrainSize_Callback, true);
            app.popupmenuGrainSize.Tag = 'popupmenuGrainSize';
            app.popupmenuGrainSize.Value = 'grain dependence selection menu';
            app.checkboxNoDuctile = uicheckbox(app.uibuttongroup4);
            app.checkboxNoDuctile.ValueChangedFcn = createCallbackFcn(app, @checkboxNoDuctile_Callback, true);
            app.checkboxNoDuctile.Tag = 'checkboxNoDuctile';
            app.checkboxNoDuctile.Text = 'No ductile rheology';
            app.editGrainSize = uieditfield(app.uibuttongroup4, 'text');
            app.editGrainSize.ValueChangedFcn = createCallbackFcn(app, @editGrainSize_Callback, true);
            app.editGrainSize.Tag = 'editGrainSize';
            app.editGrainSize.Value = '1e-6';
            app.checkboxStrength = uicheckbox(app.uibuttongroup3);
            app.checkboxStrength.Tag = 'checkboxStrength';
            app.checkboxStrength.Enable = 'off';
            app.checkboxStrength.Text = 'Strength limit';
            app.checkboxStrength.Value = true;
            app.editStrengthLimit = uieditfield(app.uibuttongroup3, 'text');
            app.editStrengthLimit.Tag = 'editStrengthLimit';
            app.editStrengthLimit.Enable = 'off';
            app.editStrengthLimit.Value = 'Not implemented yet';
            app.listboxBrittle = uilistbox(app.uibuttongroup3);
            app.listboxBrittle.Items = {'Byerlee low pressure', 'Byerlee high pressure', 'Lizardite low friction', 'Lizardite plastic', 'Saturate at 300 MPa', 'Ice Friction'};
            app.listboxBrittle.Multiselect = 'on';
            app.listboxBrittle.ValueChangedFcn = createCallbackFcn(app, @listboxBrittle_Callback, true);
            app.listboxBrittle.Tag = 'listboxBrittle';
            app.listboxBrittle.Value = {'Byerlee low pressure'};
            app.editFriction = uieditfield(app.uibuttongroup3, 'text');
            app.editFriction.Tag = 'editFriction';
            app.editFriction.Enable = 'off';
            app.editFriction.Value = '0.6';
            app.grainSizeLabel = uilabel(app.uibuttongroup3);
            app.grainSizeLabel.Tag = 'text20';
            app.grainSizeLabel.Text = 'grain size (m)';
            app.uipanelGraphics = uipanel(app.figure);
            app.uipanelGraphics.Title = 'Graphics';
            app.uipanelGraphics.Tag = 'uipanel5';
            app.axesStructure = uiaxes(app.uipanelGraphics);
            app.axesStructure.Tag = 'axesStructure';
            app.axesTemperature = uiaxes(app.uipanelGraphics);
            app.axesTemperature.Tag = 'axesTemperature';
            app.axesStrength = uiaxes(app.uipanelGraphics);
            app.axesStrength.Tag = 'axesStrength';
            app.axesGrainsize = uiaxes(app.uipanelGraphics);
            app.axesGrainsize.Tag = 'axesGrainsize';
            app.pushbuttonDrawStructure = uibutton(app.uipanelGraphics, 'push');
            app.pushbuttonDrawStructure.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonDrawStructure_Callback, true);
            app.pushbuttonDrawStructure.Tag = 'pushbuttonDrawStructure';
            app.pushbuttonDrawStructure.Text = 'Redraw Structure';
            app.pushbuttonDrawTemperature = uibutton(app.uipanelGraphics, 'push');
            app.pushbuttonDrawTemperature.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonDrawTemperature_Callback, true);
            app.pushbuttonDrawTemperature.Tag = 'pushbuttonDrawTemperature';
            app.pushbuttonDrawTemperature.Text = 'Redraw Temperature';
            app.pushbuttonDrawStrength = uibutton(app.uipanelGraphics, 'push');
            app.pushbuttonDrawStrength.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonDrawStrength_Callback, true);
            app.pushbuttonDrawStrength.Tag = 'pushbuttonDrawStrength';
            app.pushbuttonDrawStrength.Text = 'Redraw Profile';
            app.uipanelActions = uipanel(app.figure);
            app.uipanelActions.Title = 'Actions';
            app.uipanelActions.Tag = 'uipanel6';
            app.pushbuttonEffectiveRheology = uibutton(app.uipanelActions, 'push');
            app.pushbuttonEffectiveRheology.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonEffectiveRheology_Callback, true);
            app.pushbuttonEffectiveRheology.Tag = 'pushbuttonEffectiveRheology';
            app.pushbuttonEffectiveRheology.Text = 'Effective rheology';
            app.pushbuttonReduceGrainSize = uibutton(app.uipanelActions, 'push');
            app.pushbuttonReduceGrainSize.Tag = 'pushbuttonReduceGrainSize';
            app.pushbuttonReduceGrainSize.Enable = 'off';
            app.pushbuttonReduceGrainSize.Text = 'Reduce grain size';
            app.pushbuttonReduceBrittle = uibutton(app.uipanelActions, 'push');
            app.pushbuttonReduceBrittle.Tag = 'pushbuttonReduceBrittle';
            app.pushbuttonReduceBrittle.Enable = 'off';
            app.pushbuttonReduceBrittle.Text = 'Reduce Brittle strength';
            app.editGraphic = uieditfield(app.uipanelActions, 'text');
            app.editGraphic.Tag = 'editGraphic';
            app.editGraphic.Value = 'Graphic Root';
            app.pushbuttonExportProfile = uibutton(app.uipanelActions, 'push');
            app.pushbuttonExportProfile.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonExportProfile_Callback, true);
            app.pushbuttonExportProfile.Tag = 'pushbuttonExportProfile';
            app.pushbuttonExportProfile.Text = 'Export Single Profile';
            app.pushbuttonExportTable = uibutton(app.uipanelActions, 'push');
            app.pushbuttonExportTable.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonExportTable_Callback, true);
            app.pushbuttonExportTable.Tag = 'pushbuttonExportTable';
            app.pushbuttonExportTable.Text = 'Export rheology table';
            app.pushbuttonExportRheology = uibutton(app.uipanelActions, 'push');
            app.pushbuttonExportRheology.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonExportRheology_Callback, true);
            app.pushbuttonExportRheology.Tag = 'pushbuttonExportRheology';
            app.pushbuttonExportRheology.Text = 'Export Rheology Figure';
            app.pushbuttonExportModel = uibutton(app.uipanelActions, 'push');
            app.pushbuttonExportModel.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonExportModel_Callback, true);
            app.pushbuttonExportModel.Tag = 'pushbuttonExportModel';
            app.pushbuttonExportModel.Text = 'Export model';
            % Show the figure after all components are created
            app.figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RHEOL_GUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure)

                % Execute the startup function
                runStartupFcn(app, @(app)RHEOL_GUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure)
        end
    end
end